classdef Image_adquisition_goniometry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        GoniometerLabel    matlab.ui.control.Label
        AngleLabel         matlab.ui.control.Label
        AngleEditField     matlab.ui.control.EditField
        Panel              matlab.ui.container.Panel
        startButton        matlab.ui.control.Button
        MethodSwitchLabel  matlab.ui.control.Label
        MethodSwitch       matlab.ui.control.Switch
        stopCheckBox       matlab.ui.control.CheckBox
        SwitchLabel        matlab.ui.control.Label
        Switch             matlab.ui.control.ToggleSwitch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)
           method = string(app.MethodSwitch.Value); % Get status of Current method
           
           if method == "Recorded"
               [filename,path] = uigetfile("*.mp4","Open file");
               fullpath = strcat(path,filename);
               vid = VideoReader(fullpath);                         % Load Video 
               vid.CurrentTime = 0;                                 % Set  Video's current time to 0


               %%% PROCESING VARIABLES %%%
              th = 0.5;                                   % Set threshold for binarization
              se1 = strel("disk",20);                     % Set kernel for morpholgical operation
              T = [];                                     % Create vector for time
              A = [];                                     % Create vector for angle 
              while vid.hasFrame() && app.stopCheckBox.Value == 0 
                   temp = vid.readFrame();                 % Get frame
                   gray = rgb2gray(temp);                  % Transform to gray
                   binary = im2bw(gray,th);                % Get binary image 
                   actual =   imopen(binary,se1);          % Apply erosion then dilation
                   actual2 =   bwmorph(actual,'majority'); % Apply majortiy to close pixels
                    
                   %%% GET OBJECTS IN FRAME %%%
                   [labeled,numobjects]=bwlabel(actual2);                  % Label objects 
                   measurements  = regionprops(labeled,'centroid',"Area"); % Compute centroids and area
                   centroids = cat(1,measurements.Centroid);               % Concatenate centroids
                   area = cat(1,measurements.Area);                        % Concatenate area
                   [r,c]=size(centroids);  
                   % TRY AND EXCEPT (MATLAB VERSION) %
                   try
                   %%% SORT SHAPES BASED ON AREA %%%
                   [Ar,i] = sort(area);               % Sort and get index
                   % Obtaint Wrist (w), Elbow (e) and Shoulder (a)  
                   % Every circle was desinged with different radius
                   %(w : raduis = 1.5cm)
                   %(e : raduis = 2.5cm)
                   %(a : raduis = 3.5cm)
                   [w,e,a] = deal(centroids(i(1),:),centroids(i(2),:),centroids(i(3),:));
                    
                   %%% TRANSLATE VECTORS TO ELBOW %%%
                   xw = w(:,1) - e(:,1);  % transleta wrist x component to elbow x component (new x orign)
                   yw = w(:,2) - e(:,2);  % transleta wrist y component to elbow y component (new y orign)
                   xa = a(:,1) - e(:,1);  % transleta shoulder x component to elbow x component (new x orign)
                   ya = a(:,2) - e(:,2);  % transleta shoulder y component to elbow y component (new y orign)
                   v1=[xw,yw];            % Create vector 1
                   v2=[xa,ya];            % Create vector 2
                   nv1=norm(v1);          % Comput ecuclidian distance  of vector 1
                   nv2=norm(v2);          % Comput ecuclidian distance  of vector 2
                   angle = acosd(dot(v1,v2)/(nv1*nv2));      % Obtain angle 

                   angle = acosd(dot(v1,v2)/(nv1*nv2));      % Obtain angle 
                   app.AngleEditField.Value = string(angle);
                   catch                                            % In exception
                   app.AngleEditField.Value = "Unable To Capture Angle, set points correctyl";
                   end
                   imshow(temp, 'Parent', app.UIAxes);                  % Show image
                   hold(app.UIAxes);                                    % Hold on first subplot
                   plot(app.UIAxes,w(1),w(2),'r+','MarkerSize',10,'LineWidth', 1); % Plot centroids
                   plot(app.UIAxes,e(1),e(2),'g+','MarkerSize',10,'LineWidth', 1); % Plot centroids
                   plot(app.UIAxes,a(1),a(2),'b+','MarkerSize',10,'LineWidth', 1); % Plot centroids
                   line(app.UIAxes,[e(1),w(1)],[e(2),w(2)]);                       % Plot line
                   line(app.UIAxes,[e(1),a(1)],[e(2),a(2)]);                       % Plot lin
                   hold(app.UIAxes);                                    % Hold on first subplot
                   t=vid.CurrentTime ;
                   A = [A angle];
                   T = [T t];
                   plot(app.UIAxes2,T,A,'b--')
                
                   pause(1.0/vid.FrameRate);                                                % Pause so that program can process
               end


           elseif method == "Live"
               cam   = webcam;
               %axis(app.UIAxes,'image');
               th = 0.5;                                   % Set threshold for binarization
               se1 = strel("disk",20);                     % Set kernel for morpholgical operation
               T = [];                                     % Create vector for time
               A = [];                                     % Create vector for angle 
             
               time = 1;             
               while app.stopCheckBox.Value == 0
                   %%%% PRERPOCESS IMAGE %%%
                    temp =  snapshot(cam);                  % Get frame
                    gray = rgb2gray(temp);                  % Transform to gray
                    binary = im2bw(gray,th);                % Get binary image 
                    actual =   imopen(binary,se1);          % Apply erosion then dilation
                    actual2 =   bwmorph(actual,'majority'); % Apply majortiy to close pixels
                    
                    %%% GET OBJECTS IN FRAME %%%
                    [labeled,numobjects]=bwlabel(actual2);                  % Label objects 
                    measurements  = regionprops(labeled,'centroid',"Area"); % Compute centroids and area
                    centroids = cat(1,measurements.Centroid);               % Concatenate centroids
                    area = cat(1,measurements.Area);                        % Concatenate area
                    [r,c]=size(centroids);                                  % Get shape of matrix of centroid 
                    
                    % TRY AND EXCEPT (MATLAB VERSION) %
                    try
                    %%% SORT SHAPES BASED ON AREA %%%
                    [Ar,i] = sort(area);               % Sort and get index
                    % Obtaint Wrist (w), Elbow (e) and Shoulder (a)  
                    % Every circle was desinged with different radius
                    %(w : raduis = 1.5cm)
                    %(e : raduis = 2.5cm)
                    %(a : raduis = 3.5cm)
                    [w,e,a] = deal(centroids(i(1),:),centroids(i(2),:),centroids(i(3),:));
                    
                    %%% TRANSLATE VECTORS TO ELBOW %%%
                    xw = w(:,1) - e(:,1);  % transleta wrist x component to elbow x component (new x orign)
                    yw = w(:,2) - e(:,2);  % transleta wrist y component to elbow y component (new y orign)
                    xa = a(:,1) - e(:,1);  % transleta shoulder x component to elbow x component (new x orign)
                    ya = a(:,2) - e(:,2);  % transleta shoulder y component to elbow y component (new y orign)
                    v1=[xw,yw];            % Create vector 1
                    v2=[xa,ya];            % Create vector 2
                    nv1=norm(v1);          % Comput ecuclidian distance  of vector 1
                    nv2=norm(v2);          % Comput ecuclidian distance  of vector 2
                    angle = acosd(dot(v1,v2)/(nv1*nv2));      % Obtain angle 
                    app.AngleEditField.Value = string(angle);
                    %%% PLOT %%%
                    imshow(temp, 'Parent', app.UIAxes);                  % Show image
                    hold(app.UIAxes);                                    % Hold on first subplot
                    plot(app.UIAxes,w(1),w(2),'r+','MarkerSize',10,'LineWidth', 1); % Plot centroids
                    plot(app.UIAxes,e(1),e(2),'g+','MarkerSize',10,'LineWidth', 1); % Plot centroids
                    plot(app.UIAxes,a(1),a(2),'b+','MarkerSize',10,'LineWidth', 1); % Plot centroids
                    line(app.UIAxes,[e(1),w(1)],[e(2),w(2)]);                       % Plot line
                    line(app.UIAxes,[e(1),a(1)],[e(2),a(2)]);                       % Plot lin
                    hold(app.UIAxes)
                    time = time + 1;                % Get current Frame time
                    A = [A angle];                  % Append angle to vector of angles
                    T = [T time];                   % Append time to vector of time
                    plot(app.UIAxes2,T,A,'b--')
                  
                    drawnow;                        % Enable draw option to make plot dynamic
                    pause(1.0/vid.FrameRate);       % Pause so maltab does not get stuck      
                
                    catch                           % In exception
                    app.AngleEditField.Value = "Unable To Capture Angle, set points correctyl";% Raise Warning 
                    end
                
               end
              
               closePreview(cam);          % Close pop up
               clear("cam")                % Clear Cache 
               clc
               clear all
               close all
           end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Video')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Time vs Angle')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Angle [degrees] ')
            app.GoniometerLabel = uilabel(app.UIFigure);
            app.GoniometerLabel.Text = 'Goniometer';
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.startButton = uibutton(app.Panel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.MethodSwitchLabel = uilabel(app.Panel);
            app.MethodSwitchLabel.Text = 'Method';
            app.MethodSwitch = uiswitch(app.Panel, 'slider');
            app.MethodSwitch.Items = {'Live', 'Recorded'};
            app.MethodSwitch.Value = 'Live';
            app.stopCheckBox = uicheckbox(app.Panel);
            app.stopCheckBox.Text = 'stop';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_adquisition_goniometry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end