classdef analog_goniometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        startButton           matlab.ui.control.Button
        AngleEditFieldLabel   matlab.ui.control.Label
        AngleEditField        matlab.ui.control.EditField
        UIAxes2               matlab.ui.control.UIAxes
        AnalogGonometryLabel  matlab.ui.control.Label
        StopCheckBox          matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)
         T = [];                                      % Vector for time
         t = 0;                                       % Time scalar
         A = [];                                      % Vector for Angle
         a = arduino;                                 % Create Arduino Instance
         while app.StopCheckBox.Value == 0            % App runs till the user tell is to stop
            t = t + 1;                                % Add counter 
            voltage = readVoltage(a,'A0');            % Obtain analog Voltage
            ang = -50.05772339*voltage + 247.12090794; % convert voltage to angle (We obtained this using python)
            app.AngleEditField.Value = string(ang);   % put angle value in Angle field
            
            A = [A ang];                              % Append angle to Angle Vector
            T = [T t];                                % APpend time to Time Vector
            plot(app.UIAxes2,T,A,'b--')
         
         end 
         
         if app.StopCheckBox.Value == 1             % If user has stop the code
            MIN = min(A);
            MAX = max(A);
            msg = "  Min: " + string(MIN) + "  Max :" + string(MAX);
            app.AngleEditField.Value = "Finished! Statistics:" + msg; % clear angle field
            save('analog_goniometer','A')
         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Time vs Angle')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Angle [degrees] ')
            app.AnalogGonometryLabel = uilabel(app.UIFigure);
            app.AnalogGonometryLabel.Text = 'Analog Gonometry';
            app.StopCheckBox = uicheckbox(app.UIFigure);
            app.StopCheckBox.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analog_goniometer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end