classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        ListBoxLabel     matlab.ui.control.Label
        ListBox          matlab.ui.control.ListBox
        StartButton_2    matlab.ui.control.Button
        Marchaen3DLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            if app.ListBox.Value == "Alejandro"
                path = "C:\Users\Eduardo\Documents\CARRERA\8vo_semestre\BIO_4\Lab\2_Analiss_marcha\position_vectors\Alejandro.xlsx";
                
            elseif  app.ListBox.Value == "Luis"
                path = "C:\Users\Eduardo\Documents\CARRERA\8vo_semestre\BIO_4\Lab\2_Analiss_marcha\position_vectors\Luis.xlsx";
            
            elseif  app.ListBox.Value == "Junior"
                path = "C:\Users\Eduardo\Documents\CARRERA\8vo_semestre\BIO_4\Lab\2_Analiss_marcha\position_vectors\Junior.xlsx";
                  
            elseif  app.ListBox.Value == "Victor"
                path = "C:\Users\Eduardo\Documents\CARRERA\8vo_semestre\BIO_4\Lab\2_Analiss_marcha\position_vectors\Victor.xlsx";
                        
            elseif  app.ListBox.Value == "Nora"
                path = "C:\Users\Eduardo\Documents\CARRERA\8vo_semestre\BIO_4\Lab\2_Analiss_marcha\position_vectors\Nora.xlsx";
                   
            end 
            
            
            Data = xlsread(path);
            MassX	   = Data(:,1);
            MassY	   = Data(:,2);
            MassZ	   = Data(:,3);
            RightHipX  = Data(:,4);
            RightHipY  = Data(:,5);	
            RightHipZ  = Data(:,6);
            RightKneeX = Data(:,7);	
            RightKneeY = Data(:,8);	
            RightKneeZ = Data(:,9);	
            RightAnkleX= Data(:,10);
            RightAnkleY= Data(:,11);
            RightAnkleZ= Data(:,12);	
            LeftHipX   = Data(:,13);
            LeftHipY   = Data(:,14);
            LeftHipZ   = Data(:,15);
            LeftKneeX  = Data(:,16);
            LeftKneeY  = Data(:,17);
            LeftKneeZ  = Data(:,18);	
            LeftAnkleX = Data(:,19);
            LeftAnkleY = Data(:,20);
            LeftAnkleZ = Data(:,21);
            
            %%%% 3D plot %%%%
            
            for i=1:length(MassZ)
                cla(app.UIAxes);
                
                %%% Right %%%
                plot3(app.UIAxes,[MassX(i), RightHipX(i)], [MassY(i), RightHipY(i)], [MassZ(i), RightHipZ(i)], 'b-');
                hold(app.UIAxes, 'on' )
                plot3(app.UIAxes,[RightHipX(i), RightKneeX(i)], [RightHipY(i), RightKneeY(i)], [RightHipZ(i), RightKneeZ(i)], 'b-');
                hold(app.UIAxes, 'on' )
                plot3(app.UIAxes,[RightKneeX(i), RightAnkleX(i)], [RightKneeY(i), RightAnkleY(i)], [RightKneeZ(i), RightAnkleZ(i)], 'b-');
                hold(app.UIAxes, 'on' )
                %%% Left %%%
                plot3(app.UIAxes,[MassX(i), LeftHipX(i)], [MassY(i), LeftHipY(i)], [MassZ(i), LeftHipZ(i)], 'r-');
                hold(app.UIAxes, 'on' )
                plot3(app.UIAxes,[LeftHipX(i),LeftKneeX(i)], [LeftHipY(i), LeftKneeY(i)], [LeftHipZ(i),LeftKneeZ(i)], 'r-');
                hold(app.UIAxes, 'on' )
                plot3(app.UIAxes,[LeftKneeX(i), LeftAnkleX(i)], [LeftKneeY(i), LeftAnkleY(i)], [LeftKneeZ(i), LeftAnkleZ(i)], 'r-');
                set(app.UIAxes,'XLim',[0,1000],'YLim',[0,1000],'ZLim',[0,1000])
                xlabel(app.UIAxes,"X");
                ylabel(app.UIAxes,"Y");
                zlabel(app.UIAxes,"Z");
                view(app.UIAxes,10,70);
                grid(app.UIAxes,'on');
                drawnow
                pause(0.1);
                
            
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Marcha ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Alejandro', 'Luis', 'Junior', 'Victor', 'Nora'};
            app.ListBox.Value = 'Luis';
            app.StartButton_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.Marchaen3DLabel = uilabel(app.UIFigure);
            app.Marchaen3DLabel.Text = 'Marcha en 3D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end